classdef z5207471 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimevaryingfrictionSwitchLabel  matlab.ui.control.Label
        TimevaryingfrictionSwitch       matlab.ui.control.ToggleSwitch
        PlotButton                      matlab.ui.control.Button
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ddt0EditFieldLabel              matlab.ui.control.Label
        ddt0EditField                   matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        lEditFieldLabel                 matlab.ui.control.Label
        lEditField                      matlab.ui.control.NumericEditField
        kSliderLabel                    matlab.ui.control.Label
        kSlider                         matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        t_maxEditFieldLabel             matlab.ui.control.Label
        t_maxEditField                  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Set system parameters based upon values of input fields
            theta_0 = app.EditField.Value;        % Initial value of angular position
            thetaDot_0 = app.ddt0EditField.Value; % Initial value of angular velocity
            l = app.lEditField.Value;             % Length of pendulum arm
            m = app.mEditField.Value;             % Mass of pendulum bob
            g = 9.81;                             % Gravitational acceleration
            k = app.kSlider.Value;                % Steady-state friction
            t_max = app.t_maxEditField.Value;     % Upper limit of time interval for ODE solution
            
            t_varying = false;                    % Boolean value, determines whether time-varying friction should be considered   
            % If the time-varying friction switch is on, enable
            % time-varying friction
            if strcmpi(app.TimevaryingfrictionSwitch.Value,'On') == 1 
                t_varying = true;
            end  
            
            % Solve the ODE for angular position and angular velocity
            [t,v] = ode45(@(t,v) System(t,v,l,m,g,k,t_varying),[0 t_max],[theta_0; thetaDot_0]);
            
            app.UIAxes.XLim = [0, t_max]; % Set x-axis limits
            if theta_0 ~= 0 % Only set the y-axis to be tight and symmetrical if it will not cause an error (at theta_0 = 0)
                maxVal = max(abs(v(:,1))); % Find maximum absolute magnitude of angular position
                app.UIAxes.YLim = [-maxVal, maxVal]; % Set y-axis to be "tight" and symmetrical
            end
            plot(app.UIAxes,t,v(:,1)); % Plot angular position over time interval
            
            %System function, modified from parts 1 & 2 to allow for
            %toggling of time-varying friction
            function vDot = System(t,v,l,m,g,k,t_var)
                if t_var % ODE if time-varying friction is enabled
                    vDot = [v(2); -g/l*sin(v(1))-(2*exp(-0.3*t)+k)/m*v(2)];
                else % ODE if time-varying friction is disabled
                    vDot = [v(2); -g/l*sin(v(1))-k/m*v(2)];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimevaryingfrictionSwitchLabel = uilabel(app.UIFigure);
            app.TimevaryingfrictionSwitchLabel.Text = 'Time-varying friction';
            app.TimevaryingfrictionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'θ(0)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.ddt0EditFieldLabel = uilabel(app.UIFigure);
            app.ddt0EditFieldLabel.Text = 'dθ/dt(0)';
            app.ddt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.Limits = [0.1 Inf];
            app.mEditField.Value = 1;
            app.lEditFieldLabel = uilabel(app.UIFigure);
            app.lEditFieldLabel.Text = 'l';
            app.lEditField = uieditfield(app.UIFigure, 'numeric');
            app.lEditField.Limits = [0.1 Inf];
            app.lEditField.Value = 1;
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [0.1 0.9];
            app.kSlider.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.kSlider.Value = 0.3;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angular Position of Pendulum System')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'θ(t) (rad)')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-1 1];
            app.t_maxEditFieldLabel = uilabel(app.UIFigure);
            app.t_maxEditFieldLabel.Text = 't_max';
            app.t_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.t_maxEditField.Limits = [0 Inf];
            app.t_maxEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z5207471

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end